classdef project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Button              matlab.ui.control.Button
        xEditFieldLabel     matlab.ui.control.Label
        xEditField          matlab.ui.control.EditField
        yEditFieldLabel     matlab.ui.control.Label
        yEditField          matlab.ui.control.EditField
        zEditFieldLabel     matlab.ui.control.Label
        zEditField          matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        tminEditFieldLabel  matlab.ui.control.Label
        tminEditField       matlab.ui.control.EditField
        tmaxEditFieldLabel  matlab.ui.control.Label
        tmaxEditField       matlab.ui.control.EditField
        RightPanel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        bTextAreaLabel      matlab.ui.control.Label
        bTextArea           matlab.ui.control.TextArea
        nTextAreaLabel      matlab.ui.control.Label
        nTextArea           matlab.ui.control.TextArea
        rTextAreaLabel      matlab.ui.control.Label
        rTextArea           matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
        title(app.UIAxes, {'График'});
        x=get(app.xEditField,'Value');
        y=get(app.yEditField,'Value');
        z=get(app.zEditField,'Value');
        axes(app.UIAxes);
        try
        t=str2sym(get(app.tminEditField,'Value')):pi/500:str2sym(get(app.tmaxEditField,'Value'));
        catch
        title(app.UIAxes, {'Неправильно введенные данные'});
        return
        end
        try
        plot3(app.UIAxes, eval(x),eval(y),eval(z)); grid(app.UIAxes,'on');
        zlabel(app.UIAxes,'Z');
        catch
        title(app.UIAxes, {'Неправильно введенные данные'});
        return
        end
        try
            R=[str2sym(x),str2sym(y),str2sym(z)];
            tch=get(app.EditField,'Value');
            if (length(split(tch))==1) 
                t2=str2sym(split(tch));
            else 
            syms t
            tch=split(tch);
            e1=str2sym(strcat(x,'==',tch(1)));
            e2=str2sym(strcat(y,'==',tch(2)));
            e3=str2sym(strcat(z,'==',tch(3)));
            eqns=[e1,e2,e3];
            t2=solve(eqns,t);
            end

t1=t; 
syms t
R_dot = diff(R,t);
R_ddot = diff(R_dot,t);
th = R_dot/norm(R_dot);
bh = cross(R_dot,R_ddot)/norm(cross(R_dot,R_ddot));
nh=dot(cross(R_dot,R_ddot),R_dot);
nh=nh/norm(nh);
disp(subs(th,{t},{t2}));

try
m1=subs(th,{t},{t2});
qc = string(m1);
v1=qc(1);
for i=2:length(m1)
    v1=v1+'  '+qc(i);
end
set(app.rTextArea,'Value',v1,'Fontsize',12);
catch
set(app.rTextArea,'Value','No answer','Fontsize',12);
end

try
m2=subs(bh,{t},{t2});
qc = string(m2);
v1=qc(1);
for i=2:length(m2)
    v1=v1+'  '+qc(i);
end
set(app.bTextArea,'Value',v1,'Fontsize',12);
catch
set(app.bTextArea,'Value','No answer','Fontsize',12);
end

try
m3=subs(nh,{t},{t2});
qc = string(m3);
v1=qc(1);
for i=2:length(m3)
    v1=v1+'  '+qc(i);
end
set(app.nTextArea,'Value',v1,'Fontsize',12);
catch
set(app.nTextArea,'Value','No answer','Fontsize',12);
end
        catch
            set(app.rTextArea,'Value', 'Неправильно введенные данные точки');
        end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'text');
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'text');
            app.zEditFieldLabel = uilabel(app.LeftPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.LeftPanel, 'text');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'Точка'; ''};
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Инструкция';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Введите параметрическую функцию с единственным параметром t, его макс и мин значение, а также точку(координаты через пробел), в которой будет найден репер Френе. Арифметические операции при записи функции записываются по правилам матлаба(если присутствует степень или второй множитель не константа, то ставим точку перед знаком операции, например: t.*t, sin(t).^2; e^x=exp(x) и тд).'};
            app.tminEditFieldLabel = uilabel(app.LeftPanel);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tminEditField = uieditfield(app.LeftPanel, 'text');
            app.tmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'График')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.bTextAreaLabel = uilabel(app.RightPanel);
            app.bTextAreaLabel.Text = {'b'; ''};
            app.bTextArea = uitextarea(app.RightPanel);
            app.nTextAreaLabel = uilabel(app.RightPanel);
            app.nTextAreaLabel.Text = {'n'; ''};
            app.nTextArea = uitextarea(app.RightPanel);
            app.rTextAreaLabel = uilabel(app.RightPanel);
            app.rTextAreaLabel.Text = 'r';
            app.rTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end